classdef DoAnCuoiKy_Nhom4AnhEmSieuNhan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GiithiunhmTab                 matlab.ui.container.Tab
        NHM4ANHEMSIUNHNLabel          matlab.ui.control.Label
        TrnNgcHuyLabel                matlab.ui.control.Label
        TrnHongAnhLabel               matlab.ui.control.Label
        PhanNgTinThnhLabel            matlab.ui.control.Label
        LHongDngLabel                 matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        VitcodetngLabel               matlab.ui.control.Label
        ThitkgiaodinLabel             matlab.ui.control.Label
        LmWordTchphnLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        BintphonthinbocoLabel         matlab.ui.control.Label
        LmWordHiquyLabel              matlab.ui.control.Label
        LmWordohmLabel                matlab.ui.control.Label
        LmWordTmnghimLabel            matlab.ui.control.Label
        LmWordNisuyLabel              matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        NghimTab                      matlab.ui.container.Tab
        a                             matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        GiiButton                     matlab.ui.control.Button
        ab                            matlab.ui.control.Label
        gx                            matlab.ui.control.Label
        ptgx                          matlab.ui.control.EditField
        GPT                           matlab.ui.control.Label
        NhpphngtrnhLabel              matlab.ui.control.Label
        pt                            matlab.ui.control.EditField
        NhpsaischophpEditFieldLabel   matlab.ui.control.Label
        ss                            matlab.ui.control.NumericEditField
        ChnphngphpgiiDropDownLabel    matlab.ui.control.Label
        ppg                           matlab.ui.control.DropDown
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        kq                            matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        lap                           matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        NS                            matlab.ui.control.Label
        GiiButton_2                   matlab.ui.control.Button
        ChnphngphpnisuyLabel          matlab.ui.control.Label
        phuongthucns                  matlab.ui.control.DropDown
        NhpphngtrnhLabel_2            matlab.ui.control.Label
        nsx                           matlab.ui.control.EditField
        NhpphngtrnhLabel_3            matlab.ui.control.Label
        nsy                           matlab.ui.control.EditField
        NhpgitrcnnisuyLabel           matlab.ui.control.Label
        nsgt                          matlab.ui.control.NumericEditField
        NhpphngtrnhLabel_4            matlab.ui.control.Label
        nskqpt                        matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        KtqunisuyLabel                matlab.ui.control.Label
        nskq                          matlab.ui.control.NumericEditField
        HiQuyTab                      matlab.ui.container.Tab
        HQBPTT                        matlab.ui.control.Label
        GiiButton_3                   matlab.ui.control.Button
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        pphq                          matlab.ui.control.DropDown
        NhpphngtrnhLabel_5            matlab.ui.control.Label
        hqx                           matlab.ui.control.EditField
        NhpphngtrnhLabel_6            matlab.ui.control.Label
        hqy                           matlab.ui.control.EditField
        NhpgitrcnnisuyLabel_2         matlab.ui.control.Label
        gtdd                          matlab.ui.control.NumericEditField
        NhpphngtrnhLabel_7            matlab.ui.control.Label
        kqhqpt                        matlab.ui.control.EditField
        KtqunisuyLabel_2              matlab.ui.control.Label
        kqhq                          matlab.ui.control.NumericEditField
        UIAxes3                       matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        DHTGD                         matlab.ui.control.Label
        GiiButton_4                   matlab.ui.control.Button
        ChnphngphpohmDropDownLabel    matlab.ui.control.Label
        CPPDH                         matlab.ui.control.DropDown
        NhpbcnhyhLabel                matlab.ui.control.Label
        dhh                           matlab.ui.control.NumericEditField
        ChndnguvoDropDownLabel        matlab.ui.control.Label
        CDDV                          matlab.ui.control.DropDown
        ChnsaisDropDownLabel          matlab.ui.control.Label
        SSCC                          matlab.ui.control.DropDown
        NhpgitrcnnisuyLabel_6         matlab.ui.control.Label
        dhketqua                      matlab.ui.control.NumericEditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        dlx                           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        dly                           matlab.ui.control.EditField
        NhphmEditFieldLabel           matlab.ui.control.Label
        nhapfx                        matlab.ui.control.EditField
        NhpgitrcntnhohmLabel          matlab.ui.control.Label
        gttdh                         matlab.ui.control.NumericEditField
        TchphnTab                     matlab.ui.container.Tab
        gx_2                          matlab.ui.control.Label
        GiiButton_5                   matlab.ui.control.Button
        NhpgitrcnnisuyLabel_7         matlab.ui.control.Label
        kqtp                          matlab.ui.control.NumericEditField
        ChnphngphptchphnLabel         matlab.ui.control.Label
        PPTP                          matlab.ui.control.DropDown
        ChndnguvoDropDownLabel_2      matlab.ui.control.Label
        DDV                           matlab.ui.control.DropDown
        NhpdliuxLabel                 matlab.ui.control.Label
        ndlx                          matlab.ui.control.EditField
        NhphmLabel                    matlab.ui.control.Label
        nhfx                          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2      matlab.ui.control.Label
        ndly                          matlab.ui.control.EditField
        CntrnEditFieldLabel           matlab.ui.control.Label
        cantren                       matlab.ui.control.NumericEditField
        CndiEditFieldLabel            matlab.ui.control.Label
        canduoi                       matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        sopt                          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GiiButton_2
        function GiiButton_2Pushed(app, event)
            x_str = app.nsx.Value;
            xa = str2num(x_str);
            y_str = app.nsy.Value;
            ya = str2num(y_str);
            x0 = app.nsgt.Value;
            switch app.phuongthucns.Value
                case 'Newton'
                    syms x;
                    dathuc = NewtonInterpolation(xa,ya,x);
                    giatri = NewtonInterpolation(xa,ya,x0);
                case 'Lagrange'
                    syms x;
                    dathuc = Lagrange(xa,ya,x);
                    giatri = Lagrange(xa,ya,x0);
            end
            app.nskqpt.Value = char(dathuc);
            app.nskq.Value = giatri;
            fplot(app.UIAxes2,dathuc, [min(xa), max(xa)], 'DisplayName', 'Đa thức nội suy');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2,xa, ya, 'ro', 'DisplayName', 'Dữ liệu thực');
            plot(app.UIAxes2,x0,giatri, 'bs', 'DisplayName', 'Giá trị nội suy');
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            title(app.UIAxes2,'Đồ thị hàm số nội suy và dữ liệu thực');
            legend(app.UIAxes2,'show');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: GiiButton_3
        function GiiButton_3Pushed(app, event)
            x_str = app.hqx.Value;
            xa = str2num(x_str);
            y_str = app.hqy.Value;
            ya = str2num(y_str);
            x0 = app.gtdd.Value;
            syms x
            switch app.pphq.Value
                case 'Tuyến tính'
                    [a1, a0] = HoiQuyTuyenTinh(xa, ya);
                    y_pt =  a0 + a1*x; 
                case 'Hàm mũ'
                    [a1, a0] = HoiQuyHamMu(xa, ya);
                    y_pt = a0*x^a1;
                case 'Mũ e'
                    [a1, a0] = HoiQuyMue(xa, ya);
                    y_pt = a0*exp(a1*x);
            end  
            app.kqhqpt.Value = char(y_pt);
            y_pt = matlabFunction(y_pt);
            app.kqhq.Value = y_pt(x0);
            plot(app.UIAxes3, xa, ya, 'o', 'DisplayName', 'Dữ liệu thực');
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, xa, y_pt(xa), 'r-', 'DisplayName', 'Hàm hồi quy');
            plot(app.UIAxes3, x0, y_pt(x0), 'bs', 'DisplayName', 'Dự đoán');
            xlabel(app.UIAxes3, 'x');
            ylabel(app.UIAxes3, 'y');
            title(app.UIAxes3, 'Hồi quy và Dự đoán');
            legend(app.UIAxes3, 'show');
            grid(app.UIAxes3, 'on');
            hold(app.UIAxes3, 'off');
        end

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
            fx = str2func(['@(x)', app.pt.Value]);
            saiso = app.ss.Value;
            switch app.ppg.Value
                case 'Chia đôi'
                    a = app.a.Value;
                    b =  app.b.Value;            
                    [result, n] = chiadoi(fx, a, b, saiso);
                case 'Lặp'
                    a = app.a.Value;
                    b =  app.b.Value;
                    fp = str2func(['@(x)',app.ptgx.Value]);
                    [result, n] = lap(fx,fp,a,b,saiso);
                case 'Newton'
                    a = app.a.Value;
                    b =  app.b.Value;            
                    [result, n] = tieptuyen(app.pt.Value, a, b, saiso);          
            end
            app.kq.Value = result;
            app.lap.Value= n;
            fplot(app.UIAxes,fx, 'DisplayName', 'f(x)');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'f(x)');
            title(app.UIAxes,'Đồ thị của phương trình f(x)');
            legend(app.UIAxes,'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off'); 
        end

        % Value changed function: CDDV
        function CDDVValueChanged(app, event)
            value = app.CDDV.Value;
            switch value 
                case 'Dữ liệu x y'
                    app.dlx.Visible ='on';
                    app.dly.Visible ='on';
                    app.nhapfx.Visible='off';
                    app.dhh.Visible = 'off';
                    app.NhpdliuxEditFieldLabel.Visible ='on';
                    app.NhpdliuyEditFieldLabel.Visible ='on';
                    app.NhphmEditFieldLabel.Visible='off';
                    app.NhpbcnhyhLabel.Visible = 'off';
                case 'Hàm số'
                    app.dlx.Visible ='off';
                    app.dly.Visible ='off';
                    app.nhapfx.Visible='on';
                    app.dhh.Visible = 'on';
                    app.NhpdliuxEditFieldLabel.Visible ='off';
                    app.NhpdliuyEditFieldLabel.Visible ='off';
                    app.NhphmEditFieldLabel.Visible='on';
                    app.NhpbcnhyhLabel.Visible = 'on';
            end
        end

        % Value changed function: CPPDH
        function CPPDHValueChanged(app, event)
            value = app.CPPDH.Value;
            switch value
                case 'Xấp xỉ trung tâm'
                    app.SSCC.Visible = 'off';
                    app.ChnsaisDropDownLabel.Visible = 'off';
                otherwise
                    app.SSCC.Visible = 'on';
                    app.ChnsaisDropDownLabel.Visible = 'on';
            end
        end

        % Button pushed function: GiiButton_4
        function GiiButton_4Pushed(app, event)
            switch app.CDDV.Value
                case 'Dữ liệu x y'
                    ndl = 0;
                    x_str = app.dlx.Value;
                    xa = str2num(x_str);
                    y_str = app.dly.Value;
                    ya = str2num(y_str);
                    h = xa(2) - xa(1);
                case 'Hàm số'
                    ndl = 1;
                    fx = str2func(['@(x)', app.nhapfx.Value]);
                    h = app.dhh.Value;
            end
            switch app.SSCC.Value
                case 'O(h)'
                    stt = 1;
                case 'O(h^2)'
                    stt = 2;
            end
            xdh = app.gttdh.Value;
            switch app.CPPDH.Value
                case 'Xấp xỉ tiến'
                    if ndl == 0
                        kqdx = DHTienXY(xa,ya,h,xdh,stt);
                    else
                        kqdx = DHTienHam(fx,h,xdh,stt);
                    end
                case 'Xấp xỉ lùi'
                    if ndl == 0
                        kqdx = DHLuiXY(xa,ya,h,xdh,stt);
                    else
                        kqdx = DHLuiHam(fx,h,xdh,stt);
                    end
                case 'Xấp xỉ trung tâm'
                    if ndl == 0
                        kqdx = DHTrungTamXY(xa,ya,h,xdh);
                    else
                        kqdx = DHTrungTamHam(fx,h,xdh);
                    end
            end
            app.dhketqua.Value = kqdx;
        end

        % Value changed function: DDV
        function DDVValueChanged(app, event)
            value = app.DDV.Value;
            switch value
                case 'Dữ liệu x y'
                    app.ndlx.Visible ='on';
                    app.ndly.Visible ='on';
                    app.nhfx.Visible='off';
                    app.sopt.Visible ='off';
                    app.cantren.Visible = 'off';
                    app.canduoi.Visible = 'off';
                    app.NhpdliuxLabel.Visible ='on';
                    app.NhpdliuyEditFieldLabel_2.Visible ='on';
                    app.NhphmLabel.Visible='off';
                    app.NhpNEditFieldLabel.Visible = 'off';
                    app.CntrnEditFieldLabel.Visible='off';
                    app.CndiEditFieldLabel.Visible = 'off';
                case 'Hàm số'
                    app.ndlx.Visible ='off';
                    app.ndly.Visible ='off';
                    app.nhfx.Visible='on';
                    app.sopt.Visible ='on';
                    app.cantren.Visible = 'on';
                    app.canduoi.Visible = 'on';
                    app.NhpdliuxLabel.Visible ='off';
                    app.NhpdliuyEditFieldLabel_2.Visible ='off';
                    app.NhphmLabel.Visible='on';   
                    app.NhpNEditFieldLabel.Visible = 'on';
                    app.CntrnEditFieldLabel.Visible='on';
                    app.CndiEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: GiiButton_5
        function GiiButton_5Pushed(app, event)
            switch app.DDV.Value
                case 'Dữ liệu x y'
                    ndl = 0;
                    x_str = app.ndlx.Value;
                    xa = str2num(x_str);
                    y_str = app.ndly.Value;
                    ya = str2num(y_str);
                case 'Hàm số'
                    ndl = 1;
                    fx = str2func(['@(x)', app.nhfx.Value]);
                    N = app.sopt.Value;
                    b = app.cantren.Value;
                    a = app.canduoi.Value;
            end
            switch app.PPTP.Value
                case 'Hình thang'
                    if ndl == 0
                        kqtp = TichPhanHinhThangXY(xa,ya);
                    else
                        kqtp = TichPhanHinhThangHam(fx,a,b,N);
                    end
                case 'Simpson 1/3'
                    if ndl == 0
                        kqtp = TichPhanSimpson13XY(xa,ya);
                    else 
                        kqtp = TichPhanSimpson13Ham(fx,a,b,N);
                    end
                case 'Simpson 3/8'
                    if ndl == 0
                        kqtp = TichPhanSimpson38XY(xa,ya);
                    else
                        kqtp = TichPhanSimpson38Ham(fx,a,b,N);
                    end
            end
            app.kqtp.Value = kqtp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.NHM4ANHEMSIUNHNLabel = uilabel(app.GiithiunhmTab);
            app.NHM4ANHEMSIUNHNLabel.Text = 'NHÓM 4 ANH EM SIÊU NHÂN ';
            app.TrnNgcHuyLabel = uilabel(app.GiithiunhmTab);
            app.TrnNgcHuyLabel.Text = 'Trần Ngọc Huy';
            app.TrnHongAnhLabel = uilabel(app.GiithiunhmTab);
            app.TrnHongAnhLabel.Text = 'Trần Hoàng Anh';
            app.PhanNgTinThnhLabel = uilabel(app.GiithiunhmTab);
            app.PhanNgTinThnhLabel.Text = 'Phan Ngô Tiến Thịnh';
            app.LHongDngLabel = uilabel(app.GiithiunhmTab);
            app.LHongDngLabel.Text = 'Lê Hoàng Dương';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = '7fa6c5f31ac4af08a0411b659d22210b.jpg';
            app.Image_2 = uiimage(app.GiithiunhmTab);
            app.Image_2.ImageSource = 'a4ec2589b6ec9b0883d3f84678890de2.jpg';
            app.Image_3 = uiimage(app.GiithiunhmTab);
            app.Image_3.ImageSource = '90570addee2645866a597530721f37fd.jpg';
            app.Image_4 = uiimage(app.GiithiunhmTab);
            app.Image_4.ImageSource = '6f3b8f2937e5c326da3f327112cf819f.jpg';
            app.VitcodetngLabel = uilabel(app.GiithiunhmTab);
            app.VitcodetngLabel.Text = 'Viết code tổng';
            app.ThitkgiaodinLabel = uilabel(app.GiithiunhmTab);
            app.ThitkgiaodinLabel.Text = 'Thiết kế giao diện';
            app.LmWordTchphnLabel = uilabel(app.GiithiunhmTab);
            app.LmWordTchphnLabel.Text = 'Làm Word: Tích phân';
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = '';
            app.BintphonthinbocoLabel = uilabel(app.GiithiunhmTab);
            app.BintphonthinbocoLabel.Text = 'Biên tập, hoàn thiện báo cáo';
            app.LmWordHiquyLabel = uilabel(app.GiithiunhmTab);
            app.LmWordHiquyLabel.Text = 'Làm Word: Hồi quy';
            app.LmWordohmLabel = uilabel(app.GiithiunhmTab);
            app.LmWordohmLabel.Text = 'Làm Word: Đạo hàm';
            app.LmWordTmnghimLabel = uilabel(app.GiithiunhmTab);
            app.LmWordTmnghimLabel.Text = 'Làm Word: Tìm nghiệm';
            app.LmWordNisuyLabel = uilabel(app.GiithiunhmTab);
            app.LmWordNisuyLabel.Text = 'Làm Word: Nội suy';
            app.Label_2 = uilabel(app.GiithiunhmTab);
            app.Label_2.Text = '22207114';
            app.Label_3 = uilabel(app.GiithiunhmTab);
            app.Label_3.Text = '22207005';
            app.Label_4 = uilabel(app.GiithiunhmTab);
            app.Label_4.Text = '22207122';
            app.Label_5 = uilabel(app.GiithiunhmTab);
            app.Label_5.Text = '22207111';
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.a = uieditfield(app.NghimTab, 'numeric');
            app.b = uieditfield(app.NghimTab, 'numeric');
            app.GiiButton = uibutton(app.NghimTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.ab = uilabel(app.NghimTab);
            app.ab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
            app.gx = uilabel(app.NghimTab);
            app.gx.Text = 'Nhập hàm lặp g(x): ';
            app.ptgx = uieditfield(app.NghimTab, 'text');
            app.GPT = uilabel(app.NghimTab);
            app.GPT.Text = 'GIẢI PHƯƠNG TRÌNH';
            app.NhpphngtrnhLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình f(x): ';
            app.pt = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép: ';
            app.ss = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphpgiiDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphpgiiDropDownLabel.Text = 'Chọn phương pháp giải';
            app.ppg = uidropdown(app.NghimTab);
            app.ppg.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ppg.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm: ';
            app.kq = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.lap = uieditfield(app.NghimTab, 'numeric');
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.NS = uilabel(app.NisuyTab);
            app.NS.Text = 'ĐA THỨC NỘI SUY';
            app.GiiButton_2 = uibutton(app.NisuyTab, 'push');
            app.GiiButton_2.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_2Pushed, true);
            app.GiiButton_2.Text = 'Giải';
            app.ChnphngphpnisuyLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy';
            app.phuongthucns = uidropdown(app.NisuyTab);
            app.phuongthucns.Items = {'Newton', 'Lagrange'};
            app.phuongthucns.Value = 'Lagrange';
            app.NhpphngtrnhLabel_2 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_2.Text = 'Nhập dữ liệu x: ';
            app.nsx = uieditfield(app.NisuyTab, 'text');
            app.NhpphngtrnhLabel_3 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_3.Text = 'Nhập dữ liệu y: ';
            app.nsy = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy: ';
            app.nsgt = uieditfield(app.NisuyTab, 'numeric');
            app.NhpphngtrnhLabel_4 = uilabel(app.NisuyTab);
            app.NhpphngtrnhLabel_4.Text = 'Kết quả đa thức nội suy: ';
            app.nskqpt = uieditfield(app.NisuyTab, 'text');
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Đồ thị')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.KtqunisuyLabel = uilabel(app.NisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy ';
            app.nskq = uieditfield(app.NisuyTab, 'numeric');
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.HQBPTT = uilabel(app.HiQuyTab);
            app.HQBPTT.Text = 'HỒI QUY BÌNH PHƯƠNG TỐI THIỂU';
            app.GiiButton_3 = uibutton(app.HiQuyTab, 'push');
            app.GiiButton_3.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_3Pushed, true);
            app.GiiButton_3.Text = 'Giải';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HiQuyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.pphq = uidropdown(app.HiQuyTab);
            app.pphq.Items = {'Tuyến tính', 'Hàm mũ', 'Mũ e'};
            app.pphq.Value = 'Tuyến tính';
            app.NhpphngtrnhLabel_5 = uilabel(app.HiQuyTab);
            app.NhpphngtrnhLabel_5.Text = 'Nhập dữ liệu x: ';
            app.hqx = uieditfield(app.HiQuyTab, 'text');
            app.NhpphngtrnhLabel_6 = uilabel(app.HiQuyTab);
            app.NhpphngtrnhLabel_6.Text = 'Nhập dữ liệu y: ';
            app.hqy = uieditfield(app.HiQuyTab, 'text');
            app.NhpgitrcnnisuyLabel_2 = uilabel(app.HiQuyTab);
            app.NhpgitrcnnisuyLabel_2.Text = 'Nhập giá trị cần dự đoán: ';
            app.gtdd = uieditfield(app.HiQuyTab, 'numeric');
            app.NhpphngtrnhLabel_7 = uilabel(app.HiQuyTab);
            app.NhpphngtrnhLabel_7.Text = 'Kết quả phương trình hồi quy: ';
            app.kqhqpt = uieditfield(app.HiQuyTab, 'text');
            app.KtqunisuyLabel_2 = uilabel(app.HiQuyTab);
            app.KtqunisuyLabel_2.Text = 'Kết quả dự đoán ';
            app.kqhq = uieditfield(app.HiQuyTab, 'numeric');
            app.UIAxes3 = uiaxes(app.HiQuyTab);
            title(app.UIAxes3, 'Đồ thị')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.DHTGD = uilabel(app.ohmTab);
            app.DHTGD.Text = 'ĐẠO HÀM';
            app.GiiButton_4 = uibutton(app.ohmTab, 'push');
            app.GiiButton_4.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_4Pushed, true);
            app.GiiButton_4.Text = 'Giải';
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.CPPDH = uidropdown(app.ohmTab);
            app.CPPDH.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.CPPDH.ValueChangedFcn = createCallbackFcn(app, @CPPDHValueChanged, true);
            app.CPPDH.Value = 'Xấp xỉ trung tâm';
            app.NhpbcnhyhLabel = uilabel(app.ohmTab);
            app.NhpbcnhyhLabel.Text = 'Nhập bước nhảy h: ';
            app.dhh = uieditfield(app.ohmTab, 'numeric');
            app.ChndnguvoDropDownLabel = uilabel(app.ohmTab);
            app.ChndnguvoDropDownLabel.Text = 'Chọn dạng đầu vào';
            app.CDDV = uidropdown(app.ohmTab);
            app.CDDV.Items = {'Dữ liệu x y', 'Hàm số'};
            app.CDDV.ValueChangedFcn = createCallbackFcn(app, @CDDVValueChanged, true);
            app.CDDV.Value = 'Dữ liệu x y';
            app.ChnsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChnsaisDropDownLabel.Text = 'Chọn sai số';
            app.SSCC = uidropdown(app.ohmTab);
            app.SSCC.Items = {'O(h)', 'O(h^2)'};
            app.SSCC.Value = 'O(h)';
            app.NhpgitrcnnisuyLabel_6 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_6.Text = 'Kết quả đạo hàm: ';
            app.dhketqua = uieditfield(app.ohmTab, 'numeric');
            app.NhpdliuxEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.dlx = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.dly = uieditfield(app.ohmTab, 'text');
            app.NhphmEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmEditFieldLabel.Text = 'Nhập hàm số';
            app.nhapfx = uieditfield(app.ohmTab, 'text');
            app.NhpgitrcntnhohmLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmLabel.Text = 'Nhập giá trị cần tính đạo hàm:';
            app.gttdh = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.gx_2 = uilabel(app.TchphnTab);
            app.gx_2.Text = 'TÍCH PHÂN';
            app.GiiButton_5 = uibutton(app.TchphnTab, 'push');
            app.GiiButton_5.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_5Pushed, true);
            app.GiiButton_5.Text = 'Giải';
            app.NhpgitrcnnisuyLabel_7 = uilabel(app.TchphnTab);
            app.NhpgitrcnnisuyLabel_7.Text = 'Kết quả tích phân: ';
            app.kqtp = uieditfield(app.TchphnTab, 'numeric');
            app.ChnphngphptchphnLabel = uilabel(app.TchphnTab);
            app.ChnphngphptchphnLabel.Text = 'Chọn phương pháp tích phân';
            app.PPTP = uidropdown(app.TchphnTab);
            app.PPTP.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PPTP.Value = 'Hình thang';
            app.ChndnguvoDropDownLabel_2 = uilabel(app.TchphnTab);
            app.ChndnguvoDropDownLabel_2.Text = 'Chọn dạng đầu vào';
            app.DDV = uidropdown(app.TchphnTab);
            app.DDV.Items = {'Dữ liệu x y', 'Hàm số'};
            app.DDV.ValueChangedFcn = createCallbackFcn(app, @DDVValueChanged, true);
            app.DDV.Value = 'Dữ liệu x y';
            app.NhpdliuxLabel = uilabel(app.TchphnTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x:';
            app.ndlx = uieditfield(app.TchphnTab, 'text');
            app.NhphmLabel = uilabel(app.TchphnTab);
            app.NhphmLabel.Text = 'Nhập hàm:';
            app.nhfx = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y:';
            app.ndly = uieditfield(app.TchphnTab, 'text');
            app.CntrnEditFieldLabel = uilabel(app.TchphnTab);
            app.CntrnEditFieldLabel.Text = 'Cận trên:';
            app.cantren = uieditfield(app.TchphnTab, 'numeric');
            app.CndiEditFieldLabel = uilabel(app.TchphnTab);
            app.CndiEditFieldLabel.Text = 'Cận dưới:';
            app.canduoi = uieditfield(app.TchphnTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.sopt = uieditfield(app.TchphnTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnCuoiKy_Nhom4AnhEmSieuNhan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end